classdef appIC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        ADJUNTARIMGENESButton       matlab.ui.control.Button
        ListBox                     matlab.ui.control.ListBox
        GENERARDESCRIPCIONESButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        ImagenesRuta   % Ruta de las imágenes
    end
    
    methods (Access = private)
        
        % FUNCTIONS
        
        % Attention Function
        function [contextVector, attentionWeights] = spatialAttention(app,hidden,features,parametersDecoder)
        
        % Recuperamos los pesos para la atención espacial
        weights1 = parametersDecoder.attention.Weights1;
        bias1 = parametersDecoder.attention.Bias1;
        weights2 = parametersDecoder.attention.Weights2;
        bias2 = parametersDecoder.attention.Bias2;
        weightsV = parametersDecoder.attention.WeightsV;
        biasV = parametersDecoder.attention.BiasV;
        
        % Obtenemos las dimensiones del modelo
        [embeddingDimension,numFeatures,miniBatchSize] = size(features);
        numHiddenUnits = size(weights1,1);
        
        
        Y1 = reshape(features,embeddingDimension, numFeatures*miniBatchSize);
        Y1 = fullyconnect(Y1,weights1,bias1,DataFormat="CB"); % Transformamos la dimensión del embedding en la dimensión del estado oculto mediante los pesos
        Y1 = reshape(Y1,numHiddenUnits,numFeatures,miniBatchSize);
        
        
        Y2 = fullyconnect(hidden,weights2,bias2,DataFormat="CB"); % Aplicamos los pesos necesarios al estado oculto
        Y2 = reshape(Y2,numHiddenUnits,1,miniBatchSize);
        
        scores = tanh(Y1 + Y2); % A la suma le aplicamos la función de activación tanh
        scores = reshape(scores, numHiddenUnits, numFeatures*miniBatchSize); 
        
        attentionWeights = fullyconnect(scores,weightsV,biasV,DataFormat="CB"); % Aplicamos los últimos pesos de acuerdo a la fórmula
        attentionWeights = reshape(attentionWeights,1,numFeatures,miniBatchSize);
        attentionWeights = softmax(attentionWeights,DataFormat="SCB");
        
        % Context.
        contextVector = attentionWeights .* features; % Aplicamos los pesos de atención al vector de contexto
        
        % El resultado de esta función sera un vector de contexto de tamaño [embeddingDim numCaracteristicas tamañoLote]
        
        end
        
        
        % Función del embedding
        function Z = embedding(app,sequences, weights)
        % Se le introduce un lote de índices
        [N, T] = size(sequences, 1:2); % N es el número de secuencias y T la longitud de la secuencia
        numWords = reshape(sequences, N*T, 1);
        
        Z = weights(:, numWords); % Usamos los índices para seleccionar los vectores de embedding correspondientes a cada palabra
        
        Z = reshape(Z, [], N, T);
        
        % El resultado es embeddingDim x numSecuencias x longitudSecuencia
        
        end
        
        
        % Función extracción de características de las imágenes
        function X = extractImageFeatures(app,net,X,executionEnvironment)
        
        X = app.normalization(net,X,executionEnvironment);
        
        X = predict(net,X); % Obtenemos las características visuales con la red preentrenada
        
        sz = size(X); % La salida será en formato [H x W x C]: regiones de imagen (H, W), con C canales de características
        
        numFeatures = sz(1) * sz(2); % Número de características
        inputSizeEncoder = sz(3); % Canales
        miniBatchSize = sz(4); % Tamaño del lote
        
        X = reshape(X,[numFeatures inputSizeEncoder miniBatchSize]); % Adaptamos la salida para trabajar con el resto del codificador
        
        end


        % Función del codificador
        function Y = modelEncoder(app,X,parametersEncoder)
        
        [numFeatures,inputSizeEncoder,miniBatchSize] = size(X);
        
        % Fully connect
        weights = parametersEncoder.fc.Weights;
        bias = parametersEncoder.fc.Bias;
        embeddingDimension = size(weights,1);
        
        X = permute(X,[2 1 3]);
        
        X = reshape(X,inputSizeEncoder,numFeatures*miniBatchSize); % Convertimos la matriz a 2D para poder hacer el fully connected
        Y = fullyconnect(X,weights,bias,DataFormat="CB"); % Transformamos la dimensión de los canales en la dimensión del embedding
        Y = reshape(Y,embeddingDimension,numFeatures,miniBatchSize);
        
        Y = relu(Y); % Aplicamos ReLU
        
        % Esta función devolverá una matriz de tamaño [embeddingDim numCaracteristicas tamañoLote]
        
        end
        
        
        % Función del decodificador
        function [Y,state,attentionWeights] = modelDecoder(app,decoderInput,parametersDecoder,features,state)
        
        hiddenState = state.gru.HiddenState; % Recuperamos el estado oculto del gru
        
        [contextVector, attentionWeights] = app.spatialAttention(hiddenState,features,parametersDecoder);
        contextVector = app.aoa(hiddenState,contextVector,parametersDecoder);
        
        
        % Embedding
        weights = parametersDecoder.emb.Weights; % Obtenemos los pesos del embedding para aplicarselos
        emb = app.embedding(decoderInput,weights); % Le pasamos una lista de palabras
        
        % Concatenate
        Y = cat(1,contextVector,emb); % Concatenamos el vector de contexto con la salida del embedding
        
        % GRU
        inputWeights = parametersDecoder.gru.InputWeights;
        recurrentWeights = parametersDecoder.gru.RecurrentWeights;
        bias = parametersDecoder.gru.Bias;
        [Y, hiddenState] = gru(Y, hiddenState, inputWeights, recurrentWeights, bias, DataFormat="CBT"); % DataFormat="CBT"
        
        % Actualizamos estado oculto
        state.gru.HiddenState = hiddenState;
        
        % Fully connect
        weights = parametersDecoder.fc1.Weights;
        bias = parametersDecoder.fc1.Bias;
        Y = fullyconnect(Y,weights,bias,DataFormat="CB");
        
        % Fully connect
        weights = parametersDecoder.fc2.Weights;
        bias = parametersDecoder.fc2.Bias;
        Y = fullyconnect(Y,weights,bias,DataFormat="CB");
        
        % Devuelve una matriz de dimensiones [tamañoVocabulario tamañoLote] que son
        % unas puntuaciones para cada palabra, ordenado por su índice
        
        end


        % Función Beam Search
        function [words,attentionScores] = beamSearch(app,X,beamIndex,parametersEncoder,parametersDecoder, ...
            enc,maxNumWords)
        
        numFeatures = size(X,1);
        numHiddenUnits = size(parametersDecoder.attention.Weights1,1);
        
        features = app.modelEncoder(X,parametersEncoder);
        
        % Iniciamos estado oculto
        state = struct;
        state.gru.HiddenState = dlarray(zeros([numHiddenUnits 1],"like",X));
        
        % Iniciamos lista de candidatos, habrá tantos candidatos como nuestra
        % variable Beam Index
        candidates = struct;
        candidates.State = state;
        candidates.Words = "<start>"; % Toda palabra comenzará con el token <start>
        candidates.Score = 0;
        candidates.AttentionScores = dlarray(zeros([numFeatures maxNumWords],"like",X));
        candidates.StopFlag = false; % Indicador sobre si la frase esta terminada
        
        % En la variable caption se recogerá la descripción con mejores
        % puntuaciones
        caption = struct;
        caption.Words = [];
        caption.Score = -100; % Empieza en -100 para que cualquier descripción inicial tenga mejor puntuación que ella
        caption.AttentionScores = dlarray(zeros([numFeatures maxNumWords],"like",X));
        
        t = 0;
        
        % Coeficiente de penalización para no repetir palabras
        stopwords = ["a","an","the","in","on","at","by","with","about","of","for",...
                     "to","from","up","down","under","over","and","or","but","if",...
                     "is","are","was","were","this","that","these","those"];
        
        swPenaltyCoef = 0.3; % La penalización para las stop words será mínima
        penaltyCoef = 0.9; % Valor de la penalización, puede modificarse
        stopBonus = 0; % Bonus para incitarle a terminar antes de llegar al límite de palabras
        
        
        while t <= maxNumWords
            t = t + 1;
            
            candidatesNew = []; % Candidatos para ser la nueva palabra
            
            % Loop over candidates
            for i = numel(candidates):-1:1 % Iteramos de atras hacia delante
                
                
                % Si una frase candidata ha terminado
                if candidates(i).StopFlag
                    if caption.Score < candidates(i).Score % Si tiene mejor puntuacion sustituímos
                        caption.Words = [candidates(i).Words];
                        caption.Score = candidates(i).Score;
                        caption.AttentionScores = [candidates(i).AttentionScores];
                    end
                    
                    candidates(i) = []; % Eliminamos la frase de las candidatas
                    continue
                end
        
                
                % Obtenemos las propiedades de las candidatas para actualizarlos
                state = candidates(i).State;
                words = candidates(i).Words;
                score = candidates(i).Score;
                attentionScores = candidates(i).AttentionScores;
                
                decoderInput = word2ind(enc,words(end)); % Se introduce el índice de la última palabra
                [YPred,state,attentionScores(:,t)] = app.modelDecoder(decoderInput,parametersDecoder,features,state); % Obtenemos puntuaciones de las palabras
                
                YPred = softmax(YPred,DataFormat="CB"); % Normalizamos valores
        
                [scoresTop,idxTop] = maxk(extractdata(YPred),beamIndex); % Obtenemos las "BeamIndex" (el valor que hayamos definido) mejores
                idxTop = gather(idxTop);
                
                % Bucle entre las opciones
                for j = 1:beamIndex
                    candidate = struct;
                    
                    candidateWord = ind2word(enc,idxTop(j)); % Convertimos el índice en palabra
                    candidateScore = scoresTop(j); % Obtenemos su puntuación normalizada
        
                    % PENALIZACION
                     isStop = any(strcmpi(candidateWord, stopwords)); % Si la palabra candidata es un stopword
                    if isStop
                        repeatedCount = sum(strcmpi(candidateWord, words)); % Contamos cuantas hay entre las palabras anteriormente elegidas
                        repetitionPenalty = swPenaltyCoef * repeatedCount;
                    else
                        repeatedCount = sum(strcmpi(candidateWord, words));
                        repetitionPenalty = penaltyCoef * repeatedCount;
                    end
        
                    if candidateWord == "<stop>" && t >= floor(0.6 * maxNumWords) % Si la palabra candidata es <stop> y llevamos más del 60/100 de la frase aplicamos bonus
                        stopBonus = 6 + 4 * ((t / maxNumWords)^2.5); % Hemos elegido función exponencial
                    end
        
                    finalScore = score + log(candidateScore) - repetitionPenalty + stopBonus;
        
                    
                    if candidateWord == "<stop>" % Si la palabra candidata es <stop> activamos indicador
                        candidate.StopFlag = true;
                        attentionScores(:,t+1:end) = [];
                    else
                        candidate.StopFlag = false;
                    end
                    
                    % Añadimos palabra a candidatas
                    candidate.State = state;
                    candidate.Words = [words candidateWord];
                    candidate.Score = finalScore;
                    candidate.AttentionScores = attentionScores;
                    
                    candidatesNew = [candidatesNew candidate];
                end
            end
            
            if isempty(candidatesNew) % Si no hay candidatos nuevos ya que las palabras candidatas estaban terminadas salimos del bucle
                break;
            end
        
            % Obtenemos las mejores nuevas candidatas
            [~,idx] = maxk([candidatesNew.Score],beamIndex);
            candidates = candidatesNew(idx); % Sustituímos las nuevas por las antiguas
        
        end
        
        words = caption.Words(2:end-1); % Devolvemos las palabras sin tener en cuenta los tokens <start> y <stop>
        attentionScores = caption.AttentionScores;
        
        end
        
        
        % Función atención sobre atención
        function contextVector = aoa(app,hiddenState, contextVector, parametersDecoder)
        
        % Recuperamos los pesos 
        Wqi = parametersDecoder.attention.WeightsQi;
        biq = parametersDecoder.attention.BiasIq;
        Wvi = parametersDecoder.attention.WeightsVi;
        biv = parametersDecoder.attention.BiasIv;
        Wqg = parametersDecoder.attention.WeightsQg;
        bgq = parametersDecoder.attention.BiasGq;
        Wvg = parametersDecoder.attention.WeightsVg;
        bgv = parametersDecoder.attention.BiasGv;
        transformer = parametersDecoder.attention.transformer;
        transformerBias = parametersDecoder.attention.transformerBias;
        
        hiddenState = dlarray(single(extractdata(hiddenState)));
        
        [embeddingDimension,numFeatures,miniBatchSize] = size(contextVector);
        numHiddenUnits = size(Wqi,1);
        contextVector =reshape(contextVector,embeddingDimension, numFeatures*miniBatchSize);
        
        % Paso 1: vector de información
        %i = fullyconnect(hiddenState, Wqi, biq, DataFormat="CB") + fullyconnect(contextVector, Wvi, biv, DataFormat="CB");
        hsi = fullyconnect(hiddenState, Wqi, biq, DataFormat="CB");
        cvi = fullyconnect(contextVector, Wvi, biv, DataFormat="CB");
        hsi = reshape(hsi,numHiddenUnits,1,miniBatchSize);
        cvi = reshape(cvi,numHiddenUnits,numFeatures,miniBatchSize);
        
        i = hsi + cvi;
        % Paso 2: compuerta de atención
        %g = sigmoid(fullyconnect(hiddenState, Wqg, bgq, DataFormat="CB") + fullyconnect(contextVector, Wvg, bgv, DataFormat="CB"));
        
        hsg = fullyconnect(hiddenState, Wqg, bgq, DataFormat="CB");
        cvg = fullyconnect(contextVector, Wvg, bgv, DataFormat="CB");
        hsg = reshape(hsg,numHiddenUnits,1,miniBatchSize);
        cvg = reshape(cvg,numHiddenUnits,numFeatures,miniBatchSize);
        
        g = sigmoid(hsg + cvg);
        
        % Paso 3: combinación final
        attended = g .* i;
        
        attended = reshape(attended,numHiddenUnits,numFeatures*miniBatchSize);
        contextVector = fullyconnect(attended, transformer, transformerBias, DataFormat="CB"); % Lo transformamos para que tenga la dimension del embeddingDimension
        
        contextVector = reshape(contextVector, embeddingDimension, numFeatures, miniBatchSize);
        contextVector = squeeze(mean(contextVector, 2));
        
        % La dimensión final será [embeddingDim tamañoLote]
        
        end
        
        
        % Normalización y redimensionamiento
        function X = normalization(app,net,X,executionEnvironment)
        % Aplicamos la normalización zerocenter, esta normalización deberá
        % cambiarse en función de la red preentrenada usada
        meanR = 123.68;
        meanG = 116.779;
        meanB = 103.939;
        
        % Redimensionamos la imagen con la entrada necesaria
        inputSize = net.Layers(1).InputSize(1:2);
        X = imresize(X,inputSize);
        
        % Aplicamos la normalización
        X(:,:,1,:) = X(:,:,1,:) - meanR;  % R
        X(:,:,2,:) = X(:,:,2,:) - meanG;  % G
        X(:,:,3,:) = X(:,:,3,:) - meanB;  % B
        
        % Convertimos en dlarray
        X = single(X);
        X = dlarray(X,"SSCB");
        
        
        if (executionEnvironment == "auto" && canUseGPU) || executionEnvironment == "gpu"
            X = gpuArray(X);
        end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GENERARDESCRIPCIONESButton
        function start(app, event)
            modelNetwork = load(fullfile("attoatt/", "vgg19_attoatt2.mat")); % score BLEU 0.3056

            executionEnvironment = "auto";

            net = modelNetwork.net;
            enc = modelNetwork.enc;
            parametersEncoder = modelNetwork.parametersEncoder;
            parametersDecoder = modelNetwork.parametersDecoder;
            inputSizeNet = net.Layers(1).InputSize;
            executionEnvironment = "auto";

            for i = 1:numel(app.ImagenesRuta)
                rutaActual = app.ImagenesRuta(i);
                rutaActual = rutaActual{1};

                img = imread(rutaActual);

                X = app.extractImageFeatures(net,img,executionEnvironment);
                beamIndex = 3;
                maxNumWords = 25;
                [words, attentionScores] = app.beamSearch(X, beamIndex, parametersEncoder, parametersDecoder, enc, maxNumWords);
                
                caption = join(words);
                
                figure;
                subplot(1, 2, 1);
                imshow(img);
                title('Imagen');

                subplot(1, 2, 2);
                axis off
                text(0.5, 0.5, caption, ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle', ...
                    'FontSize', 12, ...
                    'Interpreter', 'none');
                title('Descripción generada');
            end
        end

        % Button pushed function: ADJUNTARIMGENESButton
        function adjuntarImagenes(app, event)
            [files, path] = uigetfile({'*.jpg;*.png;*.bmp','Imágenes'}, 'Selecciona imágenes', 'MultiSelect', 'on');
            if isequal(files,0)
                return
            end
            if ischar(files)
                files = {files};
            end
            app.ImagenesRuta = cellfun(@(f) fullfile(path,f), files, 'UniformOutput', false);
            app.ListBox.Items = files;
            app.ListBox.Value = files{1};
        end

        % Value changed function: ListBox
        function listbox(app, event)
            selectedFile = app.ListBox.Value;  % nombre archivo
    
            % Buscar índice en Items para coger la ruta correcta
            idx = find(strcmp(app.ListBox.Items, selectedFile));
            if isempty(idx)
                disp('Archivo no encontrado en rutas');
                return
            end
            rutaImagen = app.ImagenesRuta{idx};
            
            % Convierte a char si es string
            if isstring(rutaImagen)
                rutaImagen = char(rutaImagen);
            end
            
            % Comprueba si ruta existe
            if ~isfile(rutaImagen)
                disp('Ruta no válida o archivo no existe');
                return
            end
            
            img = imread(rutaImagen);
            imshow(img, 'Parent', app.UIAxes);
        end

        % Callback function: not associated with a component
        function axes(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GENERARDESCRIPCIONESButton = uibutton(app.GridLayout, 'push');
            app.GENERARDESCRIPCIONESButton.ButtonPushedFcn = createCallbackFcn(app, @start, true);
            app.GENERARDESCRIPCIONESButton.Text = 'GENERAR DESCRIPCIONES';
            app.ListBox = uilistbox(app.GridLayout);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @listbox, true);
            app.ADJUNTARIMGENESButton = uibutton(app.GridLayout, 'push');
            app.ADJUNTARIMGENESButton.ButtonPushedFcn = createCallbackFcn(app, @adjuntarImagenes, true);
            app.ADJUNTARIMGENESButton.Text = 'ADJUNTAR IMÁGENES';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appIC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end